classdef TagsToTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetUpTab                        matlab.ui.container.Tab
        SelectsourcefolderButton        matlab.ui.control.Button
        SelectsavefolderButton          matlab.ui.control.Button
        SelectsourcefolderTextArea      matlab.ui.control.TextArea
        SelectsavefolderTextArea        matlab.ui.control.TextArea
        BAPDropDownLabel                matlab.ui.control.Label
        BAPDropDown                     matlab.ui.control.DropDown
        ValueResultTab                  matlab.ui.container.Tab
        StartvshourButton               matlab.ui.control.Button
        VitalSignStatisticsLabel        matlab.ui.control.Label
        SelectvsnameDropDown            matlab.ui.control.DropDown
        OutputacsvfilewithLabel         matlab.ui.control.Label
        OutputacsvfilewithLabel_2       matlab.ui.control.Label
        Start10minsccButton             matlab.ui.control.Button
        MinutesValuesLabel              matlab.ui.control.Label
        SelectccnameDropDown            matlab.ui.control.DropDown
        OutputacsvfilewithLabel_12      matlab.ui.control.Label
        OutputacsvfilewithLabel_13      matlab.ui.control.Label
        Start10minstsButton             matlab.ui.control.Button
        OutputacsvfilewithLabel_14      matlab.ui.control.Label
        OutputacsvfilewithLabel_15      matlab.ui.control.Label
        HCTSAValuesLabel                matlab.ui.control.Label
        SelecttsnameDropDown            matlab.ui.control.DropDown
        OneAlgorithmTab                 matlab.ui.container.Tab
        StartonealgButton               matlab.ui.control.Button
        SelectAlgorithmDropDownLabel    matlab.ui.control.Label
        SelectAlgorithmDropDown         matlab.ui.control.DropDown
        SelectOutputDropDownLabel       matlab.ui.control.Label
        SelectoutputDropDown            matlab.ui.control.DropDown
        OutputacsvfilewithLabel_6       matlab.ui.control.Label
        OutputacsvfilewithLabel_7       matlab.ui.control.Label
        MinSpinnerLabel                 matlab.ui.control.Label
        Min1algSpinner                  matlab.ui.control.Spinner
        MaxSpinnerLabel                 matlab.ui.control.Label
        Max1algSpinner                  matlab.ui.control.Spinner
        DurationpereventsecondsLabel    matlab.ui.control.Label
        TwoAlgorithmsTab                matlab.ui.container.Tab
        StarttwoalgButton               matlab.ui.control.Button
        SelectJointOutputLabel          matlab.ui.control.Label
        Selectoutput2DropDown           matlab.ui.control.DropDown
        SelectAlgorithm2DropDownLabel   matlab.ui.control.Label
        SelectAlgorithm2DropDown        matlab.ui.control.DropDown
        SelectAlgorithm1DropDownLabel   matlab.ui.control.Label
        SelectAlgorithm1DropDown        matlab.ui.control.DropDown
        OutputacsvfilewithLabel_8       matlab.ui.control.Label
        OutputacsvfilewithLabel_9       matlab.ui.control.Label
        DurationpereventsecondsLabel_2  matlab.ui.control.Label
        MinSpinnerLabel_2               matlab.ui.control.Label
        Min2algSpinner                  matlab.ui.control.Spinner
        MaxSpinnerLabel_2               matlab.ui.control.Label
        Max2algSpinner                  matlab.ui.control.Spinner
        LogReportTab                    matlab.ui.container.Tab
        OutputacsvfilewithLabel_10      matlab.ui.control.Label
        OutputacsvfilewithLabel_11      matlab.ui.control.Label
        LogReportButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        bap_v; % input: bap version
        dict_obj; % dictionary corresponding to bap version
        source_folder; % input: source folder path
        save_folder; % input: save folder path
        algname; % input: algorithm name
        output; % output 1 table type : raw / hourly / daily table
        algname1; % input: algorithm name 1 of two
        algname2; % input: algorithm name 2 of two
        output2; % output 2 type : raw / hourly / daily table; folder with derived .mat files
        vsname;
        ccname;
        tsname;
        event_min;
        event_max;
        event_min2;
        event_max2;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set initial algorithm dictionary property value
            try
                app.dict_obj = get_dict_alg(app.BAPDropDown.Value);
            catch
                uiwait(msgbox("Fail to find BAP algorithm dictionary for this version","Error"));
            end
            
            % set initial algorithm drop down properties
            app.SelectAlgorithmDropDown.Items = strcat(app.dict_obj.onealg.Algorithm_Display_Name,' v',string(app.dict_obj.onealg.Version));
            app.SelectAlgorithmDropDown.ItemsData = string(app.dict_obj.onealg.Result_Name);
            
            % set initial algorithm 1 drop down properties
            app.SelectAlgorithm1DropDown.Items = strcat(app.dict_obj.twoalg.Algorithm_Display_Name,' v',string(app.dict_obj.twoalg.Version));
            app.SelectAlgorithm1DropDown.ItemsData = string(app.dict_obj.twoalg.Result_Name);
            
            % set initial algorithm 2 drop down properties
            app.SelectAlgorithm2DropDown.Items = strcat(app.dict_obj.twoalg.Algorithm_Display_Name,' v',string(app.dict_obj.twoalg.Version));
            app.SelectAlgorithm2DropDown.ItemsData = string(app.dict_obj.twoalg.Result_Name);
            
            % set initial algname value
            app.algname = app.SelectAlgorithmDropDown.Value;
            
            % set initial algname1 value
            app.algname1 = app.SelectAlgorithm1DropDown.Value;
            
            % set initial algname2 value
            app.algname2 = app.SelectAlgorithm2DropDown.Value;
            
            % set initial output value
            app.output = app.SelectoutputDropDown.Value;
            
            % set initial output value
            app.output2 = app.Selectoutput2DropDown.Value;
            
            % set initial vsname value
            app.vsname = app.SelectvsnameDropDown.Value;
            
            % set initial ccname value
            app.ccname = app.SelectccnameDropDown.Value;
            
            % set initial algorithm drop down properties
            app.SelecttsnameDropDown.Items = strcat(app.dict_obj.hctsa.Algorithm_Display_Name,' v',string(app.dict_obj.hctsa.Version));
            app.SelecttsnameDropDown.ItemsData = string(app.dict_obj.hctsa.Result_Name);
            app.tsname = app.SelecttsnameDropDown.Value;
            
            % set initial event duration min spinner
            app.event_min = app.Min1algSpinner.Value;
            
            % set initial event duration max spinner
            app.event_max = app.Max1algSpinner.Value;
            
            % set initial event duration min spinner
            app.event_min2 = app.Min2algSpinner.Value;
            
            % set initial event duration max spinner
            app.event_max2 = app.Max2algSpinner.Value;


        end

        % Value changed function: BAPDropDown
        function BAPDropDownValueChanged(app, event)
            value = app.BAPDropDown.Value;
            runStartupFcn(app, @startupFcn)
        end

        % Button pushed function: SelectsourcefolderButton
        function SelectsourcefolderButtonPushed(app, event)
            folder = uigetdir;
            allfileinfo = dir(strcat(folder,'\*merged.mat'));
            %allfileinfo = dir(strcat(folder,'/*merged.mat'));
            if size(allfileinfo,1)==0
                uiwait(msgbox('No merged result / log file in this folder!','Error'));
            else
                app.SelectsourcefolderTextArea.Value = folder;
                app.source_folder = string(folder);
                app.SelectsavefolderTextArea.Value = folder;
                app.save_folder = string(folder);
            end
        end

        % Value changed function: SelectsourcefolderTextArea
        function SelectsourcefolderTextAreaValueChanged(app, event)
            folder = app.SelectsourcefolderTextArea.Value;
            allfileinfo = dir(strcat(folder,'\*merged.mat'));
            %allfileinfo = dir(strcat(folder,'/*merged.mat'));
            if size(allfileinfo,1)==0
                uiwait(msgbox('No merged result / log file in this folder!','Error'));
            else
                value = app.SelectsourcefolderTextArea.Value;
                app.source_folder = string(value);
            end
        end

        % Button pushed function: SelectsavefolderButton
        function SelectsavefolderButtonPushed(app, event)
            folder = uigetdir;
            app.SelectsavefolderTextArea.Value = folder; 
            app.save_folder = string(app.SelectsavefolderTextArea.Value);
            if ~strcmp(app.save_folder, app.source_folder)
                uiwait(msgbox('Saving path changed!','Warning'));
            end
        end

        % Value changed function: SelectsavefolderTextArea
        function SelectsavefolderTextAreaValueChanged(app, event)
            value = app.SelectsavefolderTextArea.Value;
            app.save_folder = string(value);
            if ~strcmp(app.save_folder, app.source_folder)
                uiwait(msgbox('Saving path changed!','Warning'));
            end
        end

        % Value changed function: SelectAlgorithmDropDown
        function SelectAlgorithmDropDownValueChanged(app, event)
            value = app.SelectAlgorithmDropDown.Value;
            app.algname = value;
        end

        % Value changed function: SelectoutputDropDown
        function SelectoutputDropDownValueChanged(app, event)
            value = app.SelectoutputDropDown.Value;
            app.output = value;
        end

        % Button pushed function: StartonealgButton
        function StartonealgButtonPushed(app, event)
            derive_table_from_one_result(app.source_folder, app.save_folder, app.algname, app.output, app.dict_obj.onealg, app.event_min, app.event_max);
            winopen(app.save_folder);% open file saved folder after the task
        end

        % Value changed function: SelectAlgorithm1DropDown
        function SelectAlgorithm1DropDownValueChanged(app, event)
            value = app.SelectAlgorithm1DropDown.Value;
            app.algname1 = value;
        end

        % Value changed function: SelectAlgorithm2DropDown
        function SelectAlgorithm2DropDownValueChanged(app, event)
            value = app.SelectAlgorithm2DropDown.Value;
            app.algname2 = value;
        end

        % Value changed function: Selectoutput2DropDown
        function Selectoutput2DropDownValueChanged(app, event)
            value = app.Selectoutput2DropDown.Value;
            app.output2 = value;
        end

        % Button pushed function: StarttwoalgButton
        function StarttwoalgButtonPushed(app, event)
            derive_table_from_two_results(app.source_folder, app.save_folder, app.algname1, app.algname2, app.output2, app.dict_obj.twoalg, app.event_min2, app.event_max2);
            winopen(app.save_folder);% open file saved folder after the task
        end

        % Button pushed function: StartvshourButton
        function StartvshourButtonPushed(app, event)
            derive_table_from_vs_hourly_stats(app.source_folder, app.save_folder, app.vsname, app.dict_obj)
            winopen(app.save_folder);% open file saved folder after the task
        end

        % Value changed function: SelectvsnameDropDown
        function SelectvsnameDropDownValueChanged(app, event)
            value = app.SelectvsnameDropDown.Value;
            app.vsname = value;
        end

        % Button pushed function: LogReportButton
        function LogReportButtonPushed(app, event)
            get_log_report(app.source_folder, app.save_folder);
            winopen(app.save_folder);
        end

        % Button pushed function: Start10minsccButton
        function Start10minsccButtonPushed(app, event)
            derive_table_from_10mins_values(app.source_folder, app.save_folder, app.ccname, app.dict_obj)
            winopen(app.save_folder);% open file saved folder after the task
        end

        % Value changed function: SelectccnameDropDown
        function SelectccnameDropDownValueChanged(app, event)
            value = app.SelectccnameDropDown.Value;
            app.ccname = value;
        end

        % Button pushed function: Start10minstsButton
        function Start10minstsButtonPushed(app, event)
            derive_table_from_hctsa(app.source_folder, app.save_folder, app.tsname, app.dict_obj)
            winopen(app.save_folder);% open file saved folder after the task
        end

        % Value changed function: SelecttsnameDropDown
        function SelecttsnameDropDownValueChanged(app, event)
            value = app.SelecttsnameDropDown.Value;
            app.tsname = value;
        end

        % Value changed function: Min1algSpinner
        function Min1algSpinnerValueChanged(app, event)
            value = app.Min1algSpinner.Value;
            app.event_min = value;
        end

        % Value changed function: Max1algSpinner
        function Max1algSpinnerValueChanged(app, event)
            value = app.Max1algSpinner.Value;
            app.event_max = value;
        end

        % Value changed function: Min2algSpinner
        function Min2algSpinnerValueChanged(app, event)
            value = app.Min2algSpinner.Value;
            app.event_min2 = value;
        end

        % Value changed function: Max2algSpinner
        function Max2algSpinnerValueChanged(app, event)
            value = app.Max2algSpinner.Value;
            app.event_max2 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetUpTab = uitab(app.TabGroup);
            app.SetUpTab.Title = 'Set Up';
            app.SelectsourcefolderButton = uibutton(app.SetUpTab, 'push');
            app.SelectsourcefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsourcefolderButtonPushed, true);
            app.SelectsourcefolderButton.Text = 'Select .mat files folder';
            app.SelectsavefolderButton = uibutton(app.SetUpTab, 'push');
            app.SelectsavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsavefolderButtonPushed, true);
            app.SelectsavefolderButton.Text = 'Select .csv save folder';
            app.SelectsourcefolderTextArea = uitextarea(app.SetUpTab);
            app.SelectsourcefolderTextArea.ValueChangedFcn = createCallbackFcn(app, @SelectsourcefolderTextAreaValueChanged, true);
            app.SelectsavefolderTextArea = uitextarea(app.SetUpTab);
            app.SelectsavefolderTextArea.ValueChangedFcn = createCallbackFcn(app, @SelectsavefolderTextAreaValueChanged, true);
            app.BAPDropDownLabel = uilabel(app.SetUpTab);
            app.BAPDropDownLabel.Text = 'BAP';
            app.BAPDropDown = uidropdown(app.SetUpTab);
            app.BAPDropDown.Items = {'2.2', '2.0'};
            app.BAPDropDown.ItemsData = {'2_2', '2_0'};
            app.BAPDropDown.ValueChangedFcn = createCallbackFcn(app, @BAPDropDownValueChanged, true);
            app.BAPDropDown.Value = '2_2';
            app.ValueResultTab = uitab(app.TabGroup);
            app.ValueResultTab.Title = 'Value Result';
            app.StartvshourButton = uibutton(app.ValueResultTab, 'push');
            app.StartvshourButton.ButtonPushedFcn = createCallbackFcn(app, @StartvshourButtonPushed, true);
            app.StartvshourButton.Text = 'Start';
            app.VitalSignStatisticsLabel = uilabel(app.ValueResultTab);
            app.VitalSignStatisticsLabel.Text = 'Vital Sign Statistics';
            app.SelectvsnameDropDown = uidropdown(app.ValueResultTab);
            app.SelectvsnameDropDown.Items = {'HR', 'SPO2_pct', 'Pulse'};
            app.SelectvsnameDropDown.ItemsData = {'HR', 'SPO2_pct', 'Pulse'};
            app.SelectvsnameDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectvsnameDropDownValueChanged, true);
            app.SelectvsnameDropDown.Value = 'HR';
            app.OutputacsvfilewithLabel = uilabel(app.ValueResultTab);
            app.OutputacsvfilewithLabel.Text = 'File saved -- ''(Vital_Sign)_hourly_statistics_bap_v(x_x).csv''';
            app.OutputacsvfilewithLabel_2 = uilabel(app.ValueResultTab);
            app.OutputacsvfilewithLabel_2.Text = 'Columns -- [ id | srcfile | start date time | stop date time |  mean | std | skewness | kurtosis | warnings ] ';
            app.Start10minsccButton = uibutton(app.ValueResultTab, 'push');
            app.Start10minsccButton.ButtonPushedFcn = createCallbackFcn(app, @Start10minsccButtonPushed, true);
            app.Start10minsccButton.Text = 'Start';
            app.MinutesValuesLabel = uilabel(app.ValueResultTab);
            app.MinutesValuesLabel.Text = '10 Minutes Values';
            app.SelectccnameDropDown = uidropdown(app.ValueResultTab);
            app.SelectccnameDropDown.Items = {'MaxCrossCorrHR_SPO2_pct'};
            app.SelectccnameDropDown.ItemsData = {'MaxCrossCorrHR_SPO2_pct'};
            app.SelectccnameDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectccnameDropDownValueChanged, true);
            app.SelectccnameDropDown.Value = 'MaxCrossCorrHR_SPO2_pct';
            app.OutputacsvfilewithLabel_12 = uilabel(app.ValueResultTab);
            app.OutputacsvfilewithLabel_12.Text = 'File saved -- ''(Cross_Corr)_10mins_values_bap_v(x_x).csv''';
            app.OutputacsvfilewithLabel_13 = uilabel(app.ValueResultTab);
            app.OutputacsvfilewithLabel_13.Text = 'Columns -- [ id | srcfile | start date time | stop date time |  avail_s | max_crosscorr | lag_max | min_crosscorr | lag_min ] ';
            app.Start10minstsButton = uibutton(app.ValueResultTab, 'push');
            app.Start10minstsButton.ButtonPushedFcn = createCallbackFcn(app, @Start10minstsButtonPushed, true);
            app.Start10minstsButton.Text = 'Start';
            app.OutputacsvfilewithLabel_14 = uilabel(app.ValueResultTab);
            app.OutputacsvfilewithLabel_14.Text = 'File saved -- ''(Result)_hctsa_values_bap_v(x_x).csv''';
            app.OutputacsvfilewithLabel_15 = uilabel(app.ValueResultTab);
            app.OutputacsvfilewithLabel_15.Text = 'Columns -- [ id | srcfile | start date time | stop date time |  avail_s | value ]';
            app.HCTSAValuesLabel = uilabel(app.ValueResultTab);
            app.HCTSAValuesLabel.Text = 'HCTSA Values';
            app.SelecttsnameDropDown = uidropdown(app.ValueResultTab);
            app.SelecttsnameDropDown.Items = {'Hahaha'};
            app.SelecttsnameDropDown.ItemsData = {'MaxCrossCorrHR_SPO2_pct'};
            app.SelecttsnameDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecttsnameDropDownValueChanged, true);
            app.SelecttsnameDropDown.Value = 'MaxCrossCorrHR_SPO2_pct';
            app.OneAlgorithmTab = uitab(app.TabGroup);
            app.OneAlgorithmTab.Title = '1 Tag Result';
            app.StartonealgButton = uibutton(app.OneAlgorithmTab, 'push');
            app.StartonealgButton.ButtonPushedFcn = createCallbackFcn(app, @StartonealgButtonPushed, true);
            app.StartonealgButton.Text = 'Start';
            app.SelectAlgorithmDropDownLabel = uilabel(app.OneAlgorithmTab);
            app.SelectAlgorithmDropDownLabel.Text = 'Select Algorithm';
            app.SelectAlgorithmDropDown = uidropdown(app.OneAlgorithmTab);
            app.SelectAlgorithmDropDown.Items = {'Data Available: HR', 'Data Available: SPO2_pctt', 'Data Available: Resp', 'Periodic Breathing'};
            app.SelectAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAlgorithmDropDownValueChanged, true);
            app.SelectAlgorithmDropDown.Value = 'Data Available: HR';
            app.SelectOutputDropDownLabel = uilabel(app.OneAlgorithmTab);
            app.SelectOutputDropDownLabel.Text = 'Select Output';
            app.SelectoutputDropDown = uidropdown(app.OneAlgorithmTab);
            app.SelectoutputDropDown.Items = {'Daily aggregated table', 'Hourly aggregated table', 'UTC second event table'};
            app.SelectoutputDropDown.ItemsData = {'agg_daily', 'agg_hourly', 'raw_utc_s'};
            app.SelectoutputDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectoutputDropDownValueChanged, true);
            app.SelectoutputDropDown.Tooltip = {''};
            app.SelectoutputDropDown.Value = 'agg_daily';
            app.OutputacsvfilewithLabel_6 = uilabel(app.OneAlgorithmTab);
            app.OutputacsvfilewithLabel_6.Text = 'File saved -- ''(algorithm_name_version)_agg_(hourly/daily).csv''';
            app.OutputacsvfilewithLabel_7 = uilabel(app.OneAlgorithmTab);
            app.OutputacsvfilewithLabel_7.Text = 'Columns -- [ id | srcfile | time_key | available seconds | events count | event seconds | first start time | last stop time ]';
            app.MinSpinnerLabel = uilabel(app.OneAlgorithmTab);
            app.MinSpinnerLabel.Text = 'Min';
            app.Min1algSpinner = uispinner(app.OneAlgorithmTab);
            app.Min1algSpinner.Limits = [0 86400];
            app.Min1algSpinner.ValueChangedFcn = createCallbackFcn(app, @Min1algSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.OneAlgorithmTab);
            app.MaxSpinnerLabel.Text = 'Max';
            app.Max1algSpinner = uispinner(app.OneAlgorithmTab);
            app.Max1algSpinner.Limits = [0 86400];
            app.Max1algSpinner.ValueChangedFcn = createCallbackFcn(app, @Max1algSpinnerValueChanged, true);
            app.Max1algSpinner.Value = 86400;
            app.DurationpereventsecondsLabel = uilabel(app.OneAlgorithmTab);
            app.DurationpereventsecondsLabel.Text = 'Duration per event (seconds)';
            app.TwoAlgorithmsTab = uitab(app.TabGroup);
            app.TwoAlgorithmsTab.Title = '2 Tag Results';
            app.StarttwoalgButton = uibutton(app.TwoAlgorithmsTab, 'push');
            app.StarttwoalgButton.ButtonPushedFcn = createCallbackFcn(app, @StarttwoalgButtonPushed, true);
            app.StarttwoalgButton.Text = 'Start';
            app.SelectJointOutputLabel = uilabel(app.TwoAlgorithmsTab);
            app.SelectJointOutputLabel.Text = 'Select Joint Output';
            app.Selectoutput2DropDown = uidropdown(app.TwoAlgorithmsTab);
            app.Selectoutput2DropDown.Items = {'Daily aggregated table of joints', 'Hourly aggregated table of joints', 'UTC second event table of joints', 'Derived joint result .mat files of joints'};
            app.Selectoutput2DropDown.ItemsData = {'agg_daily', 'agg_hourly', 'raw_utc_s', 'derived_mat'};
            app.Selectoutput2DropDown.ValueChangedFcn = createCallbackFcn(app, @Selectoutput2DropDownValueChanged, true);
            app.Selectoutput2DropDown.Value = 'agg_daily';
            app.SelectAlgorithm2DropDownLabel = uilabel(app.TwoAlgorithmsTab);
            app.SelectAlgorithm2DropDownLabel.Text = 'Select Algorithm 2';
            app.SelectAlgorithm2DropDown = uidropdown(app.TwoAlgorithmsTab);
            app.SelectAlgorithm2DropDown.Items = {'Data Available: HR', 'Data Available: SPO2_pctt', 'Data Available: Resp', 'Periodic Breathing'};
            app.SelectAlgorithm2DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAlgorithm2DropDownValueChanged, true);
            app.SelectAlgorithm2DropDown.Value = 'Data Available: HR';
            app.SelectAlgorithm1DropDownLabel = uilabel(app.TwoAlgorithmsTab);
            app.SelectAlgorithm1DropDownLabel.Text = 'Select Algorithm 1';
            app.SelectAlgorithm1DropDown = uidropdown(app.TwoAlgorithmsTab);
            app.SelectAlgorithm1DropDown.Items = {'Data Available: HR', 'Data Available: SPO2_pctt', 'Data Available: Resp', 'Periodic Breathing'};
            app.SelectAlgorithm1DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAlgorithm1DropDownValueChanged, true);
            app.SelectAlgorithm1DropDown.Value = 'Data Available: HR';
            app.OutputacsvfilewithLabel_8 = uilabel(app.TwoAlgorithmsTab);
            app.OutputacsvfilewithLabel_8.Text = 'File saved -- ''(algorithm1_version)_X_(algorithm2_version)_agg_(daily|hourly).csv''';
            app.OutputacsvfilewithLabel_9 = uilabel(app.TwoAlgorithmsTab);
            app.OutputacsvfilewithLabel_9.Text = 'Columns -- [ id | srcfile | time | available seconds | events count | event seconds | first start time | last stop time ]';
            app.DurationpereventsecondsLabel_2 = uilabel(app.TwoAlgorithmsTab);
            app.DurationpereventsecondsLabel_2.Text = 'Duration per event (seconds)';
            app.MinSpinnerLabel_2 = uilabel(app.TwoAlgorithmsTab);
            app.MinSpinnerLabel_2.Text = 'Min';
            app.Min2algSpinner = uispinner(app.TwoAlgorithmsTab);
            app.Min2algSpinner.Limits = [0 86400];
            app.Min2algSpinner.ValueChangedFcn = createCallbackFcn(app, @Min2algSpinnerValueChanged, true);
            app.MaxSpinnerLabel_2 = uilabel(app.TwoAlgorithmsTab);
            app.MaxSpinnerLabel_2.Text = 'Max';
            app.Max2algSpinner = uispinner(app.TwoAlgorithmsTab);
            app.Max2algSpinner.Limits = [0 86400];
            app.Max2algSpinner.ValueChangedFcn = createCallbackFcn(app, @Max2algSpinnerValueChanged, true);
            app.Max2algSpinner.Value = 86400;
            app.LogReportTab = uitab(app.TabGroup);
            app.LogReportTab.Title = 'Log Report';
            app.OutputacsvfilewithLabel_10 = uilabel(app.LogReportTab);
            app.OutputacsvfilewithLabel_10.Text = 'File saved -- ''log_errors.csv''';
            app.OutputacsvfilewithLabel_11 = uilabel(app.LogReportTab);
            app.OutputacsvfilewithLabel_11.Text = 'For Developers :)';
            app.LogReportButton = uibutton(app.LogReportTab, 'push');
            app.LogReportButton.ButtonPushedFcn = createCallbackFcn(app, @LogReportButtonPushed, true);
            app.LogReportButton.Text = 'I am a developer and I speak Matlab (do I?  :)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TagsToTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end